classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AutoSwitchLabel       matlab.ui.control.Label
        AutoSwitch            matlab.ui.control.Switch
        CalculateButton       matlab.ui.control.Button
        HintButton            matlab.ui.control.Button
        output                matlab.ui.control.NumericEditField
        OutputEditFieldLabel  matlab.ui.control.Label
        OutputGaugeLabel      matlab.ui.control.Label
        B35                   matlab.ui.control.Button
        B34                   matlab.ui.control.Button
        B33                   matlab.ui.control.Button
        B32                   matlab.ui.control.Button
        B31                   matlab.ui.control.Button
        OutputGauge           matlab.ui.control.LinearGauge
        B30                   matlab.ui.control.Button
        B29                   matlab.ui.control.Button
        B28                   matlab.ui.control.Button
        B27                   matlab.ui.control.Button
        B26                   matlab.ui.control.Button
        B25                   matlab.ui.control.Button
        B24                   matlab.ui.control.Button
        B23                   matlab.ui.control.Button
        B22                   matlab.ui.control.Button
        B21                   matlab.ui.control.Button
        B20                   matlab.ui.control.Button
        B19                   matlab.ui.control.Button
        B18                   matlab.ui.control.Button
        B17                   matlab.ui.control.Button
        B16                   matlab.ui.control.Button
        B15                   matlab.ui.control.Button
        B14                   matlab.ui.control.Button
        B13                   matlab.ui.control.Button
        B12                   matlab.ui.control.Button
        B11                   matlab.ui.control.Button
        B10                   matlab.ui.control.Button
        B9                    matlab.ui.control.Button
        B8                    matlab.ui.control.Button
        B7                    matlab.ui.control.Button
        B6                    matlab.ui.control.Button
        B5                    matlab.ui.control.Button
        B4                    matlab.ui.control.Button
        B3                    matlab.ui.control.Button
        B2                    matlab.ui.control.Button
        B1                    matlab.ui.control.Button
        Image                 matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: B2
        function B2Pushed(app, event)

            if(app.B2.BackgroundColor== [1.00,1.00,1.00])
                app.B2.BackgroundColor='black';
            elseif(app.B2.BackgroundColor== [0.00,0.00,0.00])
                app.B2.BackgroundColor='white';
            end

        end

        % Button pushed function: B1
        function B1Pushed(app, event)
     
            if(app.B1.BackgroundColor== [1,1,1])
                app.B1.BackgroundColor='black';
           
            elseif(app.B1.BackgroundColor== [0.00,0.00,0.00])
                app.B1.BackgroundColor='white';
            end
            
        end

        % Button pushed function: B3
        function B3Pushed(app, event)
              
            if(app.B3.BackgroundColor== [1.00,1.00,1.00])
                app.B3.BackgroundColor='black';
                
            elseif(app.B3.BackgroundColor== [0.00,0.00,0.00])
                app.B3.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B4
        function B4Pushed(app, event)
              
            if(app.B4.BackgroundColor== [1.00,1.00,1.00])
                app.B4.BackgroundColor='black';
                
            elseif(app.B4.BackgroundColor== [0.00,0.00,0.00])
                app.B4.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B5
        function B5Pushed(app, event)
           

            if(app.B5.BackgroundColor== [1.00,1.00,1.00])
                app.B5.BackgroundColor='black';
                             
            elseif(app.B5.BackgroundColor== [0.00,0.00,0.00])
                app.B5.BackgroundColor='white';
               
            end
            
        end

        % Button pushed function: B6
        function B6Pushed(app, event)
           

            if(app.B6.BackgroundColor== [1.00,1.00,1.00])
                app.B6.BackgroundColor='black';
                
            elseif(app.B6.BackgroundColor== [0.00,0.00,0.00])
                app.B6.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B7
        function B7Pushed(app, event)
         

            if(app.B7.BackgroundColor== [1.00,1.00,1.00])
                app.B7.BackgroundColor='black';
                
            elseif(app.B7.BackgroundColor== [0.00,0.00,0.00])
                app.B7.BackgroundColor='white';
               
            end
            
        end

        % Button pushed function: B8
        function B8Pushed(app, event)
         
        
            if(app.B8.BackgroundColor== [1.00,1.00,1.00])
                app.B8.BackgroundColor='black';
                
            elseif(app.B8.BackgroundColor== [0.00,0.00,0.00])
                app.B8.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B14
        function B14Pushed(app, event)
        
            if(app.B14.BackgroundColor== [1.00,1.00,1.00])
                app.B14.BackgroundColor='black';
               
            elseif(app.B14.BackgroundColor== [0.00,0.00,0.00])
                app.B14.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B15
        function B15Pushed(app, event)
           
          
            if(app.B15.BackgroundColor== [1.00,1.00,1.00])
                app.B15.BackgroundColor='black';
                
            elseif(app.B15.BackgroundColor== [0.00,0.00,0.00])
                app.B15.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B9
        function B9Pushed(app, event)
   
            
            if(app.B9.BackgroundColor== [1.00,1.00,1.00])
                app.B9.BackgroundColor='black';
                
            elseif(app.B9.BackgroundColor== [0.00,0.00,0.00])
                app.B9.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B16
        function B16Pushed(app, event)
      
            if(app.B16.BackgroundColor== [1.00,1.00,1.00])
                app.B16.BackgroundColor='black';
                
            elseif(app.B16.BackgroundColor== [0.00,0.00,0.00])
                app.B16.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B17
        function B17Pushed(app, event)
         
            if(app.B17.BackgroundColor== [1.00,1.00,1.00])
                app.B17.BackgroundColor='black';
                
            elseif(app.B17.BackgroundColor== [0.00,0.00,0.00])
                app.B17.BackgroundColor='white';
               
            end
            
        end

        % Button pushed function: B18
        function B18Pushed(app, event)

            if(app.B18.BackgroundColor== [1.00,1.00,1.00])
                app.B18.BackgroundColor='black';
                
            elseif(app.B18.BackgroundColor== [0.00,0.00,0.00])
                app.B18.BackgroundColor='white';
             
            end
        end

        % Button pushed function: B19
        function B19Pushed(app, event)
        
            if(app.B19.BackgroundColor== [1.00,1.00,1.00])
                app.B19.BackgroundColor='black';
               
            elseif(app.B19.BackgroundColor== [0.00,0.00,0.00])
                app.B19.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B20
        function B20Pushed(app, event)


            if(app.B20.BackgroundColor== [1.00,1.00,1.00])
                app.B20.BackgroundColor='black';
               
            elseif(app.B20.BackgroundColor== [0.00,0.00,0.00])
                app.B20.BackgroundColor='white';
               
            end
            
        end

        % Button pushed function: B21
        function B21Pushed(app, event)
             
            if(app.B21.BackgroundColor== [1.00,1.00,1.00])
                app.B21.BackgroundColor='black';
                
            elseif(app.B21.BackgroundColor== [0.00,0.00,0.00])
                app.B21.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B22
        function B22Pushed(app, event)
            
            if(app.B22.BackgroundColor== [1.00,1.00,1.00])
                app.B22.BackgroundColor='black';
                             
            elseif(app.B22.BackgroundColor== [0.00,0.00,0.00])
                app.B22.BackgroundColor='white';
             
            end
            
        end

        % Button pushed function: B23
        function B23Pushed(app, event)
               

            if(app.B23.BackgroundColor== [1.00,1.00,1.00])
                app.B23.BackgroundColor='black';
               
            elseif(app.B23.BackgroundColor== [0.00,0.00,0.00])
                app.B23.BackgroundColor='white';
 
            end
            
        end

        % Button pushed function: B24
        function B24Pushed(app, event)
                   

            if(app.B24.BackgroundColor== [1.00,1.00,1.00])
                app.B24.BackgroundColor='black';
               
            elseif(app.B24.BackgroundColor== [0.00,0.00,0.00])
                app.B24.BackgroundColor='white';
               
            end
            
        end

        % Button pushed function: B25
        function B25Pushed(app, event)

            if(app.B25.BackgroundColor== [1.00,1.00,1.00])
                app.B25.BackgroundColor='black';
             
            elseif(app.B25.BackgroundColor== [0.00,0.00,0.00])
                app.B25.BackgroundColor='white';
             
            end
            
        end

        % Button pushed function: B26
        function B26Pushed(app, event)
                

            if(app.B26.BackgroundColor== [1.00,1.00,1.00])
                app.B26.BackgroundColor='black';
                
            elseif(app.B26.BackgroundColor== [0.00,0.00,0.00])
                app.B26.BackgroundColor='white';
             
            end
            
        end

        % Button pushed function: B31
        function B31Pushed(app, event)
                  
            if(app.B31.BackgroundColor== [1.00,1.00,1.00])
                app.B31.BackgroundColor='black';
              
            elseif(app.B31.BackgroundColor== [0.00,0.00,0.00])
                app.B31.BackgroundColor='white';
           
            end
            
        end

        % Button pushed function: B27
        function B27Pushed(app, event)
                   

            if(app.B27.BackgroundColor== [1.00,1.00,1.00])
                app.B27.BackgroundColor='black';
                        
            elseif(app.B27.BackgroundColor== [0.00,0.00,0.00])
                app.B27.BackgroundColor='white';
           
            end
            
        end

        % Button pushed function: B32
        function B32Pushed(app, event)
                    
            if(app.B32.BackgroundColor== [1.00,1.00,1.00])
                app.B32.BackgroundColor='black';
             
            elseif(app.B32.BackgroundColor== [0.00,0.00,0.00])
                app.B32.BackgroundColor='white';
         
            end
            
        end

        % Button pushed function: B28
        function B28Pushed(app, event)
                       
            if(app.B28.BackgroundColor== [1.00,1.00,1.00])
                app.B28.BackgroundColor='black';
                            
            elseif(app.B28.BackgroundColor== [0.00,0.00,0.00])
                app.B28.BackgroundColor='white';
            
            end
            
        end

        % Button pushed function: B29
        function B29Pushed(app, event)
      
            if(app.B29.BackgroundColor== [1.00,1.00,1.00])
                app.B29.BackgroundColor='black';
                             
            elseif(app.B29.BackgroundColor== [0.00,0.00,0.00])
                app.B29.BackgroundColor='white';
            
            end
            
        end

        % Button pushed function: B33
        function B33Pushed(app, event)
              
            
            if(app.B33.BackgroundColor== [1.00,1.00,1.00])
                app.B33.BackgroundColor='black';
               
            elseif(app.B33.BackgroundColor== [0.00,0.00,0.00])
                app.B33.BackgroundColor='white';
                
            end
            
        end

        % Button pushed function: B34
        function B34Pushed(app, event)
                
            if(app.B34.BackgroundColor== [1.00,1.00,1.00])
                app.B34.BackgroundColor='black';
               
            elseif(app.B34.BackgroundColor== [0.00,0.00,0.00])
                app.B34.BackgroundColor='white';
               
            end
            
        end

        % Button pushed function: B30
        function B30Pushed(app, event)
                     
            if(app.B30.BackgroundColor== [1.00,1.00,1.00])
                app.B30.BackgroundColor='black';
                
            elseif(app.B30.BackgroundColor== [0.00,0.00,0.00])
                app.B30.BackgroundColor='white';
               
            end
            
        end

        % Button pushed function: B35
        function B35Pushed(app, event)
                      
            if(app.B35.BackgroundColor== [1.00,1.00,1.00])
                app.B35.BackgroundColor='black';
                 
            elseif(app.B35.BackgroundColor== [0.00,0.00,0.00])
                app.B35.BackgroundColor='white';
             
            end
            
        end

        % Button pushed function: B10
        function B10ButtonPushed(app, event)
                      

            if(app.B10.BackgroundColor== [1.00,1.00,1.00])
                app.B10.BackgroundColor='black';
                              
            elseif(app.B10.BackgroundColor== [0.00,0.00,0.00])
                app.B10.BackgroundColor='white';
                
            end
        end

        % Button pushed function: B11
        function B11ButtonPushed(app, event)
                      

            if(app.B11.BackgroundColor== [1.00,1.00,1.00])
                app.B11.BackgroundColor='black';
               
            elseif(app.B11.BackgroundColor== [0.00,0.00,0.00])
                app.B11.BackgroundColor='white';
                
            end
        end

        % Button pushed function: B12
        function B12ButtonPushed(app, event)
                     

            if(app.B12.BackgroundColor== [1.00,1.00,1.00])
                app.B12.BackgroundColor='black';
             
            elseif(app.B12.BackgroundColor== [0.00,0.00,0.00])
                app.B12.BackgroundColor='white';
               
            end
        end

        % Button pushed function: B13
        function B13ButtonPushed(app, event)
                    

            if(app.B13.BackgroundColor== [1.00,1.00,1.00])
                app.B13.BackgroundColor='black';
                          
            elseif(app.B13.BackgroundColor== [0.00,0.00,0.00])
                app.B13.BackgroundColor='white';
              
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        inputnumber=zeros(7,5); 
        
        if(app.B1.BackgroundColor== [0,0,0])
            inputnumber(1,1)=1;
        elseif(app.B1.BackgroundColor== [1,1,1])
            inputnumber(1,1)=0;
        end
        
        if(app.B2.BackgroundColor== [0,0,0])
            inputnumber(1,2)=1;
        elseif(app.B2.BackgroundColor== [1,1,1])
            inputnumber(1,2)=0;
        end
        
        if(app.B3.BackgroundColor== [0,0,0])
            inputnumber(1,3)=1;
        elseif(app.B3.BackgroundColor== [1,1,1])
            inputnumber(1,3)=0;
        end
        
        if(app.B4.BackgroundColor== [0,0,0])
            inputnumber(1,4)=1;
        elseif(app.B4.BackgroundColor== [1,1,1])
            inputnumber(1,4)=0;
        end
        
        if(app.B5.BackgroundColor== [0,0,0])
            inputnumber(1,5)=1;
        elseif(app.B5.BackgroundColor== [1,1,1])
            inputnumber(1,5)=0;
        end
        
        if(app.B6.BackgroundColor== [0,0,0])
            inputnumber(2,1)=1;
        elseif(app.B6.BackgroundColor== [1,1,1])
            inputnumber(2,1)=0;
        end
        
        if(app.B7.BackgroundColor== [0,0,0])
            inputnumber(2,2)=1;
        elseif(app.B7.BackgroundColor== [1,1,1])
            inputnumber(2,2)=0;
        end
        
        if(app.B8.BackgroundColor== [0,0,0])
            inputnumber(2,3)=1;
        elseif(app.B8.BackgroundColor== [1,1,1])
            inputnumber(2,3)=0;
        end
        
        if(app.B9.BackgroundColor== [0,0,0])
            inputnumber(2,4)=1;
        elseif(app.B9.BackgroundColor== [1,1,1])
            inputnumber(2,4)=0;
        end
        
        if(app.B10.BackgroundColor== [0,0,0])
            inputnumber(2,5)=1;
        elseif(app.B10.BackgroundColor== [1,1,1])
            inputnumber(2,5)=0;
        end
        
        if(app.B11.BackgroundColor== [0,0,0])
            inputnumber(3,1)=1;
        elseif(app.B11.BackgroundColor== [1,1,1])
            inputnumber(3,1)=0;
        end
        
        if(app.B12.BackgroundColor== [0,0,0])
            inputnumber(3,2)=1;
        elseif(app.B12.BackgroundColor== [1,1,1])
            inputnumber(3,2)=0;
        end
        
        if(app.B13.BackgroundColor== [0,0,0])
            inputnumber(3,3)=1;
        elseif(app.B13.BackgroundColor== [1,1,1])
            inputnumber(3,3)=0;
        end
        
        if(app.B14.BackgroundColor== [0,0,0])
            inputnumber(3,4)=1;
        elseif(app.B14.BackgroundColor== [1,1,1])
            inputnumber(3,4)=0;
        end
        
        if(app.B15.BackgroundColor== [0,0,0])
            inputnumber(3,5)=1;
        elseif(app.B15.BackgroundColor== [1,1,1])
            inputnumber(3,5)=0;
        end
        
        if(app.B16.BackgroundColor== [0,0,0])
            inputnumber(4,1)=1;
        elseif(app.B16.BackgroundColor== [1,1,1])
            inputnumber(4,1)=0;
        end
        
        if(app.B17.BackgroundColor== [0,0,0])
            inputnumber(4,2)=1;
        elseif(app.B17.BackgroundColor== [1,1,1])
            inputnumber(4,2)=0;
        end
        
        if(app.B18.BackgroundColor== [0,0,0])
            inputnumber(4,3)=1;
        elseif(app.B18.BackgroundColor== [1,1,1])
            inputnumber(4,3)=0;
        end
        
        if(app.B19.BackgroundColor== [0,0,0])
            inputnumber(4,4)=1;
        elseif(app.B19.BackgroundColor== [1,1,1])
            inputnumber(4,4)=0;
        end
         
        if(app.B20.BackgroundColor== [0,0,0])
            inputnumber(4,5)=1;
        elseif(app.B20.BackgroundColor== [1,1,1])
            inputnumber(4,5)=0;
        end
         
        if(app.B21.BackgroundColor== [0,0,0])
            inputnumber(5,1)=1;
        elseif(app.B21.BackgroundColor== [1,1,1])
            inputnumber(5,1)=0;
        end
        
        if(app.B22.BackgroundColor== [0,0,0])
            inputnumber(5,2)=1;
        elseif(app.B22.BackgroundColor== [1,1,1])
            inputnumber(5,2)=0;
        end
        
        if(app.B23.BackgroundColor== [0,0,0])
            inputnumber(5,3)=1;
        elseif(app.B23.BackgroundColor== [1,1,1])
            inputnumber(5,3)=0;
        end
        
        if(app.B24.BackgroundColor== [0,0,0])
            inputnumber(5,4)=1;
        elseif(app.B24.BackgroundColor== [1,1,1])
            inputnumber(5,4)=0;
        end
        
        if(app.B25.BackgroundColor== [0,0,0])
            inputnumber(5,5)=1;
        elseif(app.B25.BackgroundColor== [1,1,1])
            inputnumber(5,5)=0;
        end
        
        if(app.B26.BackgroundColor== [0,0,0])
            inputnumber(6,1)=1;
        elseif(app.B26.BackgroundColor== [1,1,1])
            inputnumber(6,1)=0;
        end
        
         if(app.B27.BackgroundColor== [0,0,0])
            inputnumber(6,2)=1;
        elseif(app.B27.BackgroundColor== [1,1,1])
            inputnumber(6,2)=0;
        end

        if(app.B28.BackgroundColor== [0,0,0])
            inputnumber(6,3)=1;
        elseif(app.B28.BackgroundColor== [1,1,1])
            inputnumber(6,3)=0;
        end
        
        if(app.B29.BackgroundColor== [0,0,0])
            inputnumber(6,4)=1;
        elseif(app.B29.BackgroundColor== [1,1,1])
            inputnumber(6,4)=0;
        end

        if(app.B30.BackgroundColor== [0,0,0])
            inputnumber(6,5)=1;
        elseif(app.B30.BackgroundColor== [1,1,1])
            inputnumber(6,5)=0;
        end
        
        if(app.B31.BackgroundColor== [0,0,0])
            inputnumber(7,1)=1;
        elseif(app.B31.BackgroundColor== [1,1,1])
            inputnumber(7,1)=0;
        end
        
        if(app.B32.BackgroundColor== [0,0,0])
            inputnumber(7,2)=1;
        elseif(app.B32.BackgroundColor== [1,1,1])
            inputnumber(7,2)=0;
        end
        
        if(app.B33.BackgroundColor== [0,0,0])
            inputnumber(7,3)=1;
        elseif(app.B33.BackgroundColor== [1,1,1])
            inputnumber(7,3)=0;
        end
        
        if(app.B34.BackgroundColor== [0,0,0])
            inputnumber(7,4)=1;
        elseif(app.B34.BackgroundColor== [1,1,1])
            inputnumber(7,4)=0;
        end
       
        if(app.B35.BackgroundColor== [0,0,0])
            inputnumber(7,5)=1;
        elseif(app.B35.BackgroundColor== [1,1,1])
            inputnumber(7,5)=0;
        end


        %Reading and evaluating Neural Network system output:
        load Networks.mat
        pupuinput=inputnumber';
        pupuinput=pupuinput(:);
        NETs={net1 net2 net3 net4 net5 net5 net5 net5 net6 net7 net8 net9 };
        %OutputVal=ClusterOutput(Cluster_Net,NET,pupuinput);
        %app.output.Value = round(OutputVal);
        app.output.Value = abs(PNLMV(pupuinput',NETs));
        app.OutputGauge.Value=app.output.Value;
    
        end

        % Button pushed function: HintButton
        function HintButtonPushed(app, event)
 
 
if  app.HintButton.BackgroundColor==[1 1 1]

    app.Image.Visible=1;
    app.HintButton.BackgroundColor=[0 1 0];
   
elseif     app.HintButton.BackgroundColor==[0 1 0]

    app.Image.Visible=0;
    app.HintButton.BackgroundColor=[1 1 1];
                
end


        end

        % Value changed function: AutoSwitch
        function AutoSwitchValueChanged(app, event)
            value = app.AutoSwitch.Value;
           while value % Continue loop as long as the switch is 'On'
        app.CalculateButtonPushed();
        pause(0.1); % Adjust the pause duration to control the update frequency
    end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'white');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Picture1.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.B1 = uibutton(app.UIFigure, 'push');
            app.B1.ButtonPushedFcn = createCallbackFcn(app, @B1Pushed, true);
            app.B1.Text = {''; ''; ''; ''};
            app.B2 = uibutton(app.UIFigure, 'push');
            app.B2.ButtonPushedFcn = createCallbackFcn(app, @B2Pushed, true);
            app.B2.Text = {''; ''; ''; ''};
            app.B3 = uibutton(app.UIFigure, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3Pushed, true);
            app.B3.Text = {''; ''; ''; ''};
            app.B4 = uibutton(app.UIFigure, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4Pushed, true);
            app.B4.Text = {''; ''; ''; ''};
            app.B5 = uibutton(app.UIFigure, 'push');
            app.B5.ButtonPushedFcn = createCallbackFcn(app, @B5Pushed, true);
            app.B5.Text = {''; ''; ''; ''};
            app.B6 = uibutton(app.UIFigure, 'push');
            app.B6.ButtonPushedFcn = createCallbackFcn(app, @B6Pushed, true);
            app.B6.Text = {''; ''; ''; ''};
            app.B7 = uibutton(app.UIFigure, 'push');
            app.B7.ButtonPushedFcn = createCallbackFcn(app, @B7Pushed, true);
            app.B7.Text = {''; ''; ''; ''};
            app.B8 = uibutton(app.UIFigure, 'push');
            app.B8.ButtonPushedFcn = createCallbackFcn(app, @B8Pushed, true);
            app.B8.Text = {''; ''; ''; ''};
            app.B9 = uibutton(app.UIFigure, 'push');
            app.B9.ButtonPushedFcn = createCallbackFcn(app, @B9Pushed, true);
            app.B9.Text = {''; ''; ''; ''};
            app.B10 = uibutton(app.UIFigure, 'push');
            app.B10.ButtonPushedFcn = createCallbackFcn(app, @B10ButtonPushed, true);
            app.B10.Text = {''; ''; ''; ''};
            app.B11 = uibutton(app.UIFigure, 'push');
            app.B11.ButtonPushedFcn = createCallbackFcn(app, @B11ButtonPushed, true);
            app.B11.Text = {''; ''; ''; ''};
            app.B12 = uibutton(app.UIFigure, 'push');
            app.B12.ButtonPushedFcn = createCallbackFcn(app, @B12ButtonPushed, true);
            app.B12.Text = {''; ''; ''; ''};
            app.B13 = uibutton(app.UIFigure, 'push');
            app.B13.ButtonPushedFcn = createCallbackFcn(app, @B13ButtonPushed, true);
            app.B13.Text = {''; ''; ''; ''};
            app.B14 = uibutton(app.UIFigure, 'push');
            app.B14.ButtonPushedFcn = createCallbackFcn(app, @B14Pushed, true);
            app.B14.Text = {''; ''; ''; ''};
            app.B15 = uibutton(app.UIFigure, 'push');
            app.B15.ButtonPushedFcn = createCallbackFcn(app, @B15Pushed, true);
            app.B15.Text = {''; ''; ''; ''};
            app.B16 = uibutton(app.UIFigure, 'push');
            app.B16.ButtonPushedFcn = createCallbackFcn(app, @B16Pushed, true);
            app.B16.Text = {''; ''; ''; ''};
            app.B17 = uibutton(app.UIFigure, 'push');
            app.B17.ButtonPushedFcn = createCallbackFcn(app, @B17Pushed, true);
            app.B17.Text = {''; ''; ''; ''};
            app.B18 = uibutton(app.UIFigure, 'push');
            app.B18.ButtonPushedFcn = createCallbackFcn(app, @B18Pushed, true);
            app.B18.Text = {''; ''; ''; ''};
            app.B19 = uibutton(app.UIFigure, 'push');
            app.B19.ButtonPushedFcn = createCallbackFcn(app, @B19Pushed, true);
            app.B19.Text = {''; ''; ''; ''};
            app.B20 = uibutton(app.UIFigure, 'push');
            app.B20.ButtonPushedFcn = createCallbackFcn(app, @B20Pushed, true);
            app.B20.Text = {''; ''; ''; ''};
            app.B21 = uibutton(app.UIFigure, 'push');
            app.B21.ButtonPushedFcn = createCallbackFcn(app, @B21Pushed, true);
            app.B21.Text = {''; ''; ''; ''};
            app.B22 = uibutton(app.UIFigure, 'push');
            app.B22.ButtonPushedFcn = createCallbackFcn(app, @B22Pushed, true);
            app.B22.Text = {''; ''; ''; ''};
            app.B23 = uibutton(app.UIFigure, 'push');
            app.B23.ButtonPushedFcn = createCallbackFcn(app, @B23Pushed, true);
            app.B23.Text = {''; ''; ''; ''};
            app.B24 = uibutton(app.UIFigure, 'push');
            app.B24.ButtonPushedFcn = createCallbackFcn(app, @B24Pushed, true);
            app.B24.Text = {''; ''; ''; ''};
            app.B25 = uibutton(app.UIFigure, 'push');
            app.B25.ButtonPushedFcn = createCallbackFcn(app, @B25Pushed, true);
            app.B25.Text = {''; ''; ''; ''};
            app.B26 = uibutton(app.UIFigure, 'push');
            app.B26.ButtonPushedFcn = createCallbackFcn(app, @B26Pushed, true);
            app.B26.Text = {''; ''; ''; ''};
            app.B27 = uibutton(app.UIFigure, 'push');
            app.B27.ButtonPushedFcn = createCallbackFcn(app, @B27Pushed, true);
            app.B27.Text = {''; ''; ''; ''};
            app.B28 = uibutton(app.UIFigure, 'push');
            app.B28.ButtonPushedFcn = createCallbackFcn(app, @B28Pushed, true);
            app.B28.Text = {''; ''; ''; ''};
            app.B29 = uibutton(app.UIFigure, 'push');
            app.B29.ButtonPushedFcn = createCallbackFcn(app, @B29Pushed, true);
            app.B29.Text = {''; ''; ''; ''};
            app.B30 = uibutton(app.UIFigure, 'push');
            app.B30.ButtonPushedFcn = createCallbackFcn(app, @B30Pushed, true);
            app.B30.Text = {''; ''; ''; ''};
            app.OutputGauge = uigauge(app.UIFigure, 'linear');
            app.OutputGauge.Limits = [0 10];
            app.OutputGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.OutputGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.B31 = uibutton(app.UIFigure, 'push');
            app.B31.ButtonPushedFcn = createCallbackFcn(app, @B31Pushed, true);
            app.B31.Text = {''; ''; ''; ''};
            app.B32 = uibutton(app.UIFigure, 'push');
            app.B32.ButtonPushedFcn = createCallbackFcn(app, @B32Pushed, true);
            app.B32.Text = {''; ''; ''; ''};
            app.B33 = uibutton(app.UIFigure, 'push');
            app.B33.ButtonPushedFcn = createCallbackFcn(app, @B33Pushed, true);
            app.B33.Text = {''; ''; ''; ''};
            app.B34 = uibutton(app.UIFigure, 'push');
            app.B34.ButtonPushedFcn = createCallbackFcn(app, @B34Pushed, true);
            app.B34.Text = {''; ''; ''; ''};
            app.B35 = uibutton(app.UIFigure, 'push');
            app.B35.ButtonPushedFcn = createCallbackFcn(app, @B35Pushed, true);
            app.B35.Text = {''; ''; ''; ''};
            app.OutputGaugeLabel = uilabel(app.UIFigure);
            app.OutputGaugeLabel.Text = 'Output';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.output = uieditfield(app.UIFigure, 'numeric');
            app.HintButton = uibutton(app.UIFigure, 'push');
            app.HintButton.ButtonPushedFcn = createCallbackFcn(app, @HintButtonPushed, true);
            app.HintButton.Text = 'Hint';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.AutoSwitch = uiswitch(app.UIFigure, 'slider');
            app.AutoSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoSwitchValueChanged, true);
            app.AutoSwitchLabel = uilabel(app.UIFigure);
            app.AutoSwitchLabel.Text = 'Auto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end